classdef Control_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainWindow            matlab.ui.Figure                   % UI Figure
        output                matlab.ui.control.TextArea        
        daqSystemsPanel       matlab.ui.container.Panel          % DAQ System
        initializeSDs         matlab.ui.control.Button           % Initiali...
        sensorTest            matlab.ui.control.Button           % Sensor Test
        collectDataStart      matlab.ui.control.Button           % Collect ...
        exitDataCollect       matlab.ui.control.Button           % Exit Dat...
        launchControlsPanel   matlab.ui.container.Panel          % Launch C...
        readyLaunch1On        matlab.ui.control.Button           % Ready La...
        readyLaunch2On        matlab.ui.control.Button           % Ready La...
        LabelLamp2            matlab.ui.control.Label            % RL2 Laun...
        rL2LaunchBoxLamp      matlab.ui.control.Lamp            
        readyLaunch1Off       matlab.ui.control.Button           % Ready La...
        readyLaunch2Off       matlab.ui.control.Button           % Ready La...
        LabelLamp             matlab.ui.control.Label            % RL1 Laun...
        rL1LaunchBoxLamp      matlab.ui.control.Lamp            
        Label                 matlab.ui.control.Label            % RL1 Comm...
        rL1CommMastLamp       matlab.ui.control.Lamp            
        Label2                matlab.ui.control.Label            % RL2 Comm...
        rL2CommMastLamp       matlab.ui.control.Lamp            
        launch                matlab.ui.control.Button           % Launch
        launchLamp            matlab.ui.control.Lamp            
        launchOffLamp         matlab.ui.control.Lamp            
        launchOff             matlab.ui.control.Button           % Igniter Off
        commStartPanel        matlab.ui.container.Panel          % Communic...
        LabelNumericEditField matlab.ui.control.Label            % Comm Port
        commPort              matlab.ui.control.NumericEditField % [0 Inf]
        startComm             matlab.ui.control.Button           % Start Comm
        findPorts             matlab.ui.control.Button           % Find Ports
        endComm               matlab.ui.control.Button           % End Comm
        enableBytesAvail      matlab.ui.control.Button           % Enable B...
        abort                 matlab.ui.control.Button           % Abort
        launchBoxPanel        matlab.ui.container.Panel          % Launch Box
        systemArm             matlab.ui.control.Button           % System Arm
        systemArmLamp         matlab.ui.control.Lamp            
        systemDisarm          matlab.ui.control.Button           % System D...
        n2OFillClose          matlab.ui.control.Button           % N2O Fill...
        n2OFillCloseLamp      matlab.ui.control.Lamp            
        n2OFillOpen           matlab.ui.control.Button           % N2O Fill...
        n2OFillOpenLamp       matlab.ui.control.Lamp            
        n2OVentClose          matlab.ui.control.Button           % N2O Vent...
        n2OVentCloseLamp      matlab.ui.control.Lamp            
        n2OVentOpen           matlab.ui.control.Button           % N2O Vent...
        n2OVentOpenLamp       matlab.ui.control.Lamp            
        oxygenFillClose       matlab.ui.control.Button           % Oxygen F...
        oxygenFillCloseLamp   matlab.ui.control.Lamp            
        qdAirClose            matlab.ui.control.Button           % QD Air C...
        qdAirCloseLamp        matlab.ui.control.Lamp            
        chA                   matlab.ui.control.Button           % Ch A On
        chALamp               matlab.ui.control.Lamp            
        oxygenFillOpen        matlab.ui.control.Button           % Oxygen F...
        oxygenFillOpenLamp    matlab.ui.control.Lamp            
        qdAirOpen             matlab.ui.control.Button           % QD Air Open
        qdAirOpenLamp         matlab.ui.control.Lamp            
        aux                   matlab.ui.control.Button           % AUX On
        auxLamp               matlab.ui.control.Lamp            
        chB                   matlab.ui.control.Button           % Ch B On
        chBLamp               matlab.ui.control.Lamp            
        igniterArmLamp        matlab.ui.control.Lamp            
        igniterArm            matlab.ui.control.Button           % Igniter Arm
        igniterDisarm         matlab.ui.control.Button           % Igniter ...
        igniterContinuityLamp matlab.ui.control.Lamp            
        igniterContTest       matlab.ui.control.Button           % Igniter ...
        buzzer                matlab.ui.control.Button           % Buzzer On
        buzzerLamp            matlab.ui.control.Lamp            
        auxOff                matlab.ui.control.Button           % AUX Off
        auxOffLamp            matlab.ui.control.Lamp            
        chAOff                matlab.ui.control.Button           % Ch A Off
        chAOffLamp            matlab.ui.control.Lamp            
        chBOff                matlab.ui.control.Button           % Ch B Off
        chBOffLamp            matlab.ui.control.Lamp            
        buzzerOff             matlab.ui.control.Button           % Buzzer Off
        buzzerOffLamp         matlab.ui.control.Lamp            
    end


    properties (Access = public)
        obj1;
        daqData;
        imuData;
        loadCellData;
        daqTime;
        imuTime;
        loadCellTime;
        dontEnd = 0;
        dataFile = strcat(pwd,'\','Data_History.m');
        comHistoryFile = strcat(pwd,'\','Comm_History.m');
        daqFile = strcat(pwd,'\','DAQ_Data.m');
        imuFile = strcat(pwd,'\','IMU_Data.m');
        dataFileID;
        daqFileID;
        imuFileID;
        formatSpec = '%s\n';
        timerVal;
        baudRate = 9600;
    end

    properties (Access = private)
       received = '';
       rl1LaunchBox = 0;
       rl2LaunchBox = 0;
       rl1CommMaster = 0;
       rl2CommMaster = 0;
       textOuput;
    end

    methods (Access = private)
        
        function receiveData(app, src, event)
            
            app.received = cellstr(fscanf(src));
            app.received = strrep(app.received,sprintf('\n'),'');
%             app.received = strcat(app.received,' end');
            outputString = app.output.Value;
            if (length(outputString)>40)
               outputString = outputString(1:40); 
            end
            app.output.Value = [app.received ; outputString];
            if (app.received{1}(1) == 'z')
                app.daqData = [{(app.received{1}(2:end))}; app.daqData];
                app.daqTime = [(toc(app.timerVal)); app.daqTime];
%                 fprintf(app.daqFileID,strcat(app.received{1}(2:end),'\t\t',num2str(toc(app.timerVal)),'\n'));
                if (length(app.daqData)>150)
                   app.daqData = app.daqData(1:150);
                   app.daqTime = app.daqTime(1:150);
                end
            elseif (app.received{1}(1) == 'y')
                app.imuData = [{(app.received{1}(2:end))}; app.imuData];
                app.imuTime = [(toc(app.timerVal)); app.imuTime];
%                 fprintf(app.imuFileID,strcat(app.received{1}(2:end),'\t\t',num2str(toc(app.timerVal)),'\n'));
                if (length(app.imuData)>150)
                   app.imuData = app.imuData(1:150);
                   app.imuTime = app.imuTime(1:150);
                end
            elseif (app.received{1}(1) == 'w')
                app.loadCellData = [{(app.received{1}(2:end))}; app.loadCellData];
                app.loadCellTime = [(toc(app.timerVal)); app.loadCellTime];
                if (length(app.loadCellData)>150)
                   app.loadCellData = app.loadCellData(1:150);
                   app.loadCellTime = app.loadCellTime(1:150);
                end
            elseif (app.received{1}(1) == 'x')
                parseData(app,app.received{1}(2:end));
            end

        end

        function parseData(app, pData)
            
            switch pData
                case 'sys1'
                    app.systemArmLamp.Color = [0 1 0];
                case 'sys0'
                    app.systemArmLamp.Color = [1 0 0];
                case 'ign1'
                    app.igniterArmLamp.Color = [0 1 0];
                case 'ign0'
                    app.igniterArmLamp.Color = [1 0 0];
                case 'LBrl11'
                    app.rL1LaunchBoxLamp.Color = [0 1 0];
                    app.rl1LaunchBox = 1;
                case 'CMrl11'
                    app.rL1CommMastLamp.Color = [0 1 0];
                    app.rl1CommMaster = 1;
                case 'LBrl10'
                    app.rL1LaunchBoxLamp.Color = [1 0 0];
                    app.rl1LaunchBox = 0;
                case 'CMrl10'
                    app.rL1CommMastLamp.Color = [1 0 0];
                    app.rl1CommMaster = 0;
                case 'LBrl21'
                    app.rL2LaunchBoxLamp.Color = [0 1 0];
                    app.rl2LaunchBox = 1;
                case 'CMrl21'
                    app.rL2CommMastLamp.Color = [0 1 0];
                    app.rl2CommMaster = 1;
                case 'LBrl20'
                    app.rL2LaunchBoxLamp.Color = [1 0 0];
                    app.rl2LaunchBox = 0;
                case 'CMrl20'
                    app.rL2CommMastLamp.Color = [1 0 0];
                    app.rl2CommMaster = 0;
                case 'ictP'
                    app.igniterContinuityLamp.Color = [0 1 0];
                case 'ictF'
                    app.igniterContinuityLamp.Color = [1 0 0];                    
                case 'buz1'
                    app.buzzerLamp.Color = [0 1 0];
                    app.buzzerOffLamp.Color = [1 0 0];
                case 'buz0'
                    app.buzzerOffLamp.Color = [0 1 0];
                    app.buzzerLamp.Color = [1 0 0];
                case 'n2fo'
                    app.n2OFillOpenLamp.Color = [0 1 0];
                    app.n2OFillCloseLamp.Color = [1 0 0];
                case 'n2fc'
                    app.n2OFillCloseLamp.Color = [0 1 0];
                    app.n2OFillOpenLamp.Color = [1 0 0];
                case 'n2vo'
                    app.n2OVentOpenLamp.Color = [0 1 0];
                    app.n2OVentCloseLamp.Color = [1 0 0];
                case 'n2vc'
                    app.n2OVentCloseLamp.Color = [0 1 0];
                    app.n2OVentOpenLamp.Color = [1 0 0];
                case 'oxyo'
                    app.oxygenFillOpenLamp.Color = [0 1 0];
                    app.oxygenFillCloseLamp.Color = [1 0 0];
                case 'oxyc'
                    app.oxygenFillCloseLamp.Color = [0 1 0];
                    app.oxygenFillOpenLamp.Color = [1 0 0];
                case 'qdao'
                    app.qdAirOpenLamp.Color = [0 1 0];
                    app.qdAirCloseLamp.Color = [1 0 0];
                case 'qdac'
                    app.qdAirCloseLamp.Color = [0 1 0];
                    app.qdAirOpenLamp.Color = [1 0 0];
                case 'aux1'
                    app.auxLamp.Color = [0 1 0];
                    app.auxOffLamp.Color = [1 0 0];
                case 'chA1'
                    app.chALamp.Color = [0 1 0];
                    app.chAOffLamp.Color = [1 0 0];
                case 'chB1'
                    app.chBLamp.Color = [0 1 0];
                    app.chBOffLamp.Color = [1 0 0];
                case 'aux0'
                    app.auxOffLamp.Color = [0 1 0];
                    app.auxLamp.Color = [1 0 0];
                case 'chA0'
                    app.chAOffLamp.Color = [0 1 0];
                    app.chALamp.Color = [1 0 0];
                case 'chB0'
                    app.chBOffLamp.Color = [0 1 0];
                    app.chBLamp.Color = [1 0 0]; 
                case 'LBlau'
                    app.launchLamp.Color = [0 1 0];
                    app.launchOffLamp.Color = [1 0 0];
                case 'LBnolau'
                    app.launchOffLamp.Color = [0 1 0];
                    app.launchLamp.Color = [1 0 0];
                otherwise
                    outputString = app.output.Value;
                    app.output.Value = ['Unrecognized Status Code' ; outputString];
            end
            
        end
        

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timerVal = tic;
            app.commPort.Value = 4;
            app.daqFileID = fopen(app.daqFile, 'a+');
            app.imuFileID = fopen(app.imuFile, 'a+');
            timeID = cellstr(datetime);
            fprintf(app.daqFileID,['\n','\n',timeID{1,1},'\n']);
            fprintf(app.imuFileID,['\n','\n',timeID{1,1},'\n']);
        end

        % startComm button pushed function
        function startCommPushed(app)
            app.dontEnd = 1;
            port = app.commPort.Value;
            portString = ['COM' num2str(port)];
            
            % Find a serial port object.
            app.obj1 = instrfind('Type', 'serial', 'Port', portString, 'Tag', '');
             
            % Create the serial port object if it does not exist
            % otherwise use the object that was found.
            if isempty(app.obj1)
                app.obj1 = serial(portString);
            else
                fclose(app.obj1);
                app.obj1 = app.obj1(1);
            end
            
            % Configure instrument object, obj1.
            set(app.obj1, 'Terminator', {13,13});
            % Configure instrument object, obj1.
            set(app.obj1, 'Timeout', 10.0);
            set(app.obj1, 'BaudRate', app.baudRate);
            
            set(app.obj1, 'BytesAvailableFcnMode', 'terminator');
%             src = app.obj1;
            app.obj1.BytesAvailableFcn = {@app.receiveData};
            
%             Connect to instrument object, obj1.
            if isvalid(app.obj1)
                fopen(app.obj1);
                outputString = app.output.Value;
                app.output.Value = ['Port Connected'; outputString];
            else
                outputString = app.output.Value;
                app.output.Value = ['Port not valid'; outputString];
            end
            
            app.obj1.RecordDetail = 'verbose';
            app.obj1.RecordName = strcat(pwd,'\','Comm_History.m');
            app.obj1.RecordMode = 'index';
%             record(app.obj1,'on');
            
            app.dataFileID = fopen(app.dataFile, 'a+');
            timeID = cellstr(datetime);
%             fprintf(app.dataFileID,['\n','\n',timeID{1,1},'\n']);
            
            fprintf(app.obj1, '+++');
            pause(1);
            fprintf(app.obj1, 'b');
            outputString = app.output.Value;
            app.output.Value = ['XBee Ready'; outputString];
            
        end

        % findPorts button pushed function
        function findPortsButtonPushed(app)
            out = instrfind('Type', 'serial');
            str = matlab.unittest.diagnostics.ConstraintDiagnostic.getDisplayableString(out);
            outputString = app.output.Value;
            app.output.Value = [str; outputString];
        end

        % End button pushed function
        function endGUIPushed(app)
%             app.dontEnd = 0;
%             fclose(app.obj1);
        end

        % readyLaunch1On button pushed function
        function readyLaunch1OnButtonPushed(app)
            fprintf(app.obj1, 'r');
            outputString = app.output.Value;
            app.output.Value = ['Ready Launch 1 On Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Ready Launch 1 On Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % readyLaunch2On button pushed function
        function readyLaunch2OnButtonPushed(app)
            fprintf(app.obj1, 'f');
            outputString = app.output.Value;
            app.output.Value = ['Ready Launch 2 On Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Ready Launch 2 On Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % launch button pushed function
        function launchButtonPushed(app)
            if (app.rl1LaunchBox && app.rl2LaunchBox && app.rl1CommMaster && app.rl2CommMaster)
                fprintf(app.obj1, 'l');
                outputString = app.output.Value;
                app.output.Value = ['Launch Button Pushed'; outputString];
%                 fprintf(app.dataFileID,strcat('Launch Button Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
            else
                outputString = app.output.Value;
                app.output.Value = ['Ready Launch 1 & 2 Not Enabled for LB and CM'; outputString];
%                 fprintf(app.dataFileID,strcat('Ready Launch 1 & 2 Not Enabled for LB and CM','\t\t',num2str(toc(app.timerVal)),'\n'));
            end
        end

        % abort button pushed function
        function abortButtonPushed(app)
            fprintf(app.obj1, 'a');
            outputString = app.output.Value;
            app.output.Value = ['Abort Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Abort Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % status button pushed function
        function statusButtonPushed(app)
            fprintf(app.obj1, 's');
            outputString = app.output.Value;
            app.output.Value = ['Status Pushed'; outputString];
%             fprintf(app.dataFileID,strcat(' Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % initializeSDs button pushed function
        function initializeSDsButtonPushed(app)
            fprintf(app.obj1, 'i');
            outputString = app.output.Value;
            app.output.Value = ['Initialize SDs Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Initialize SDs Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % sensorTest button pushed function
        function sensorTestButtonPushed(app)
            fprintf(app.obj1, 't');
            outputString = app.output.Value;
            app.output.Value = ['Sensor Test Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Sensor Test Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % collectDataStart button pushed function
        function collectDataStartButtonPushed(app)
            fprintf(app.obj1, 'c');
            outputString = app.output.Value;
            app.output.Value = ['Collect Data Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Collect Data Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % exitDataCollect button pushed function
        function exitDataCollectButtonPushed(app)
            fprintf(app.obj1, 'e');
            outputString = app.output.Value;
            app.output.Value = ['Exit Data Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Exit Data Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % systemArm button pushed function
        function systemArmButtonPushed(app)
            fprintf(app.obj1, 'p');
            outputString = app.output.Value;
            app.output.Value = ['System Arm Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('System Arm Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % buzzer button pushed function
        function buzzerButtonPushed(app)
            fprintf(app.obj1, 'b');
            outputString = app.output.Value;
            app.output.Value = ['Buzzer Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Buzzer Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % n2OFillClose button pushed function
        function n2OFillCloseButtonPushed(app)
            fprintf(app.obj1, '2');
            outputString = app.output.Value;
            app.output.Value = ['N2O Fill Close Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('N2O Fill Close Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % n2OFillOpen button pushed function
        function n2OFillOpenButtonPushed(app)
            fprintf(app.obj1, '1');
            outputString = app.output.Value;
            app.output.Value = ['N2O Fill Open Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('N2O Fill Open Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % n2OVentClose button pushed function
        function n2OVentCloseButtonPushed(app)
            fprintf(app.obj1, '4');
            outputString = app.output.Value;
            app.output.Value = ['N2O Vent Close Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('N2O Vent Close Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % n2OVentOpen button pushed function
        function n2OVentOpenButtonPushed(app)
            fprintf(app.obj1, '3');
            outputString = app.output.Value;
            app.output.Value = ['N2O Vent Open Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('N2O Vent Open Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % oxygenFillClose button pushed function
        function oxygenFillCloseButtonPushed(app)
            fprintf(app.obj1, '6');
            outputString = app.output.Value;
            app.output.Value = ['Oxygen Fill Close Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Oxygen Fill Close Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % qdAirClose button pushed function
        function qdAirCloseButtonPushed(app)
            fprintf(app.obj1, '8');
            outputString = app.output.Value;
            app.output.Value = ['QD Air Close Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('QD Air Close Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % chA button pushed function
        function chAButtonPushed(app)
            fprintf(app.obj1, '0');
            outputString = app.output.Value;
            app.output.Value = ['ChA Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('ChA Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % oxygenFillOpen button pushed function
        function oxygenFillOpenButtonPushed(app)
            fprintf(app.obj1, '5');
            outputString = app.output.Value;
            app.output.Value = ['Oxygen Fill Open Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Oxygen Fill Open Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % qdAirOpen button pushed function
        function qdAirOpenButtonPushed(app)
            fprintf(app.obj1, '7');
            outputString = app.output.Value;
            app.output.Value = ['QD Air Open Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('QD Air Open Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % aux button pushed function
        function auxButtonPushed(app)
            fprintf(app.obj1, '9');
            outputString = app.output.Value;
            app.output.Value = ['AUX Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('AUX Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % chB button pushed function
        function chBButtonPushed(app)
            fprintf(app.obj1, 'q');
            outputString = app.output.Value;
            app.output.Value = ['ChB Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('ChB Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % igniterContTest button pushed function
        function igniterContTestButtonPushed(app)
            fprintf(app.obj1, 'k');
            outputString = app.output.Value;
            app.output.Value = ['Igniter Continuity Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Igniter Continuity Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % igniterArm button pushed function
        function igniterArmButtonPushed(app)
            fprintf(app.obj1, 'n');
            outputString = app.output.Value;
            app.output.Value = ['Igniter Arm Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Igniter Arm Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % endComm button pushed function
        function endCommButtonPushed(app)
            record(app.obj1,'off');
            fclose(app.obj1);
            delete(app.obj1);
            outputString = app.output.Value;
            app.output.Value = ['Comm Ended'; outputString];
            fclose(app.dataFileID);
        end

        % systemDisarm button pushed function
        function systemDisarmButtonPushed(app)
            fprintf(app.obj1, 'm');
            outputString = app.output.Value;
            app.output.Value = ['System Disarm Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('System Disarm Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % igniterDisarm button pushed function
        function igniterDisarmButtonPushed(app)
            fprintf(app.obj1, 'o');
            outputString = app.output.Value;
            app.output.Value = ['Igniter Disarm Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Igniter Disarm Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % readyLaunch1Off button pushed function
        function readyLaunch1OffButtonPushed(app)
            fprintf(app.obj1, 'u');
            outputString = app.output.Value;
            app.output.Value = ['Ready Launch 1 Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Ready Launch 1 Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % readyLaunch2Off button pushed function
        function readyLaunch2OffButtonPushed(app)
            fprintf(app.obj1, 'j');
            outputString = app.output.Value;
            app.output.Value = ['Ready Launch 2 Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Ready Launch 2 Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % enableBytesAvail button pushed function
        function enableBytesAvailButtonPushed(app)
            set(app.obj1, 'BytesAvailableFcnMode', 'terminator');
            app.obj1.BytesAvailableFcn = {@app.receiveData};
            outputString = app.output.Value;
            app.output.Value = ['BytesAvailableFcn Re-Enabled'; outputString];
        end

        % launchOff button pushed function
        function launchOffButtonPushed(app)
            fprintf(app.obj1, 'h');
            outputString = app.output.Value;
            app.output.Value = ['Igniter Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Igniter Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % auxOff button pushed function
        function auxOffButtonPushed(app)
            fprintf(app.obj1, 'z');
            outputString = app.output.Value;
            app.output.Value = ['AUX Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('AUX Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % chAOff button pushed function
        function chAOffButtonPushed(app)
            fprintf(app.obj1, 'y');
            outputString = app.output.Value;
            app.output.Value = ['ChA Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('ChA Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % chBOff button pushed function
        function chBOffButtonPushed(app)
            fprintf(app.obj1, 'w');
            outputString = app.output.Value;
            app.output.Value = ['ChB Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('ChB Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % buzzerOff button pushed function
        function buzzerOffButtonPushed(app)
            fprintf(app.obj1, 'g');
            outputString = app.output.Value;
            app.output.Value = ['Buzzer Off Pushed'; outputString];
%             fprintf(app.dataFileID,strcat('Buzzer Off Pushed','\t\t',num2str(toc(app.timerVal)),'\n'));
        end

        % StateButton value changed function
        function StateButtonValueChanged(app)
            value = app.StateButton.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainWindow = uifigure;
            app.mainWindow.Name = 'UI Figure';
            setAutoResize(app, app.mainWindow, true)
            app.output = uitextarea(app.mainWindow);
            app.daqSystemsPanel = uipanel(app.mainWindow);
            app.daqSystemsPanel.Title = 'DAQ System';
            app.daqSystemsPanel.Units = 'pixels';
            app.initializeSDs = uibutton(app.daqSystemsPanel, 'push');
            app.initializeSDs.ButtonPushedFcn = createCallbackFcn(app, @initializeSDsButtonPushed);
            app.initializeSDs.Text = 'Initialize SDs';
            app.sensorTest = uibutton(app.daqSystemsPanel, 'push');
            app.sensorTest.ButtonPushedFcn = createCallbackFcn(app, @sensorTestButtonPushed);
            app.sensorTest.Text = 'Sensor Test';
            app.collectDataStart = uibutton(app.daqSystemsPanel, 'push');
            app.collectDataStart.ButtonPushedFcn = createCallbackFcn(app, @collectDataStartButtonPushed);
            app.collectDataStart.Text = 'Collect Data Start';
            app.exitDataCollect = uibutton(app.daqSystemsPanel, 'push');
            app.exitDataCollect.ButtonPushedFcn = createCallbackFcn(app, @exitDataCollectButtonPushed);
            app.exitDataCollect.Text = 'Exit Data Collect';
            app.launchControlsPanel = uipanel(app.mainWindow);
            app.launchControlsPanel.Title = 'Launch Controls';
            app.launchControlsPanel.Units = 'pixels';
            app.readyLaunch1On = uibutton(app.launchControlsPanel, 'push');
            app.readyLaunch1On.ButtonPushedFcn = createCallbackFcn(app, @readyLaunch1OnButtonPushed);
            app.readyLaunch1On.Text = 'Ready Launch 1 On';
            app.readyLaunch2On = uibutton(app.launchControlsPanel, 'push');
            app.readyLaunch2On.ButtonPushedFcn = createCallbackFcn(app, @readyLaunch2OnButtonPushed);
            app.readyLaunch2On.Text = 'Ready Launch 2 On';
            app.LabelLamp2 = uilabel(app.launchControlsPanel);
            app.LabelLamp2.Text = 'RL2 Launch Box';
            app.rL2LaunchBoxLamp = uilamp(app.launchControlsPanel);
            app.readyLaunch1Off = uibutton(app.launchControlsPanel, 'push');
            app.readyLaunch1Off.ButtonPushedFcn = createCallbackFcn(app, @readyLaunch1OffButtonPushed);
            app.readyLaunch1Off.Text = 'Ready Launch 1 Off';
            app.readyLaunch2Off = uibutton(app.launchControlsPanel, 'push');
            app.readyLaunch2Off.ButtonPushedFcn = createCallbackFcn(app, @readyLaunch2OffButtonPushed);
            app.readyLaunch2Off.Text = 'Ready Launch 2 Off';
            app.LabelLamp = uilabel(app.launchControlsPanel);
            app.LabelLamp.Text = 'RL1 Launch Box';
            app.rL1LaunchBoxLamp = uilamp(app.launchControlsPanel);
            app.Label = uilabel(app.launchControlsPanel);
            app.Label.Text = 'RL1 Comm Mast';
            app.rL1CommMastLamp = uilamp(app.launchControlsPanel);
            app.Label2 = uilabel(app.launchControlsPanel);
            app.Label2.Text = 'RL2 Comm Mast';
            app.rL2CommMastLamp = uilamp(app.launchControlsPanel);
            app.launch = uibutton(app.launchControlsPanel, 'push');
            app.launch.ButtonPushedFcn = createCallbackFcn(app, @launchButtonPushed);
            app.launch.Text = 'Launch';
            app.launchLamp = uilamp(app.launchControlsPanel);
            app.launchOffLamp = uilamp(app.launchControlsPanel);
            app.launchOff = uibutton(app.launchControlsPanel, 'push');
            app.launchOff.ButtonPushedFcn = createCallbackFcn(app, @launchOffButtonPushed);
            app.launchOff.Text = 'Igniter Off';
            app.commStartPanel = uipanel(app.mainWindow);
            app.commStartPanel.Title = 'Communication Intialization';
            app.commStartPanel.Units = 'pixels';
            app.LabelNumericEditField = uilabel(app.commStartPanel);
            app.LabelNumericEditField.Text = 'Comm Port';
            app.commPort = uieditfield(app.commStartPanel, 'numeric');
            app.commPort.Limits = [0 Inf];
            app.startComm = uibutton(app.commStartPanel, 'push');
            app.startComm.ButtonPushedFcn = createCallbackFcn(app, @startCommPushed);
            app.startComm.Text = 'Start Comm';
            app.findPorts = uibutton(app.commStartPanel, 'push');
            app.findPorts.ButtonPushedFcn = createCallbackFcn(app, @findPortsButtonPushed);
            app.findPorts.Text = 'Find Ports';
            app.endComm = uibutton(app.commStartPanel, 'push');
            app.endComm.ButtonPushedFcn = createCallbackFcn(app, @endCommButtonPushed);
            app.endComm.Text = 'End Comm';
            app.enableBytesAvail = uibutton(app.commStartPanel, 'push');
            app.enableBytesAvail.ButtonPushedFcn = createCallbackFcn(app, @enableBytesAvailButtonPushed);
            app.enableBytesAvail.Text = 'Enable BytesAvail';
            app.abort = uibutton(app.mainWindow, 'push');
            app.abort.ButtonPushedFcn = createCallbackFcn(app, @abortButtonPushed);
            app.abort.Text = 'Abort';
            app.launchBoxPanel = uipanel(app.mainWindow);
            app.launchBoxPanel.Title = 'Launch Box';
            app.launchBoxPanel.Units = 'pixels';
            app.systemArm = uibutton(app.launchBoxPanel, 'push');
            app.systemArm.ButtonPushedFcn = createCallbackFcn(app, @systemArmButtonPushed);
            app.systemArm.Text = 'System Arm';
            app.systemArmLamp = uilamp(app.launchBoxPanel);
            app.systemDisarm = uibutton(app.launchBoxPanel, 'push');
            app.systemDisarm.ButtonPushedFcn = createCallbackFcn(app, @systemDisarmButtonPushed);
            app.systemDisarm.Text = 'System Disarm';
            app.n2OFillClose = uibutton(app.launchBoxPanel, 'push');
            app.n2OFillClose.ButtonPushedFcn = createCallbackFcn(app, @n2OFillCloseButtonPushed);
            app.n2OFillClose.Text = 'N2O Fill Close';
            app.n2OFillCloseLamp = uilamp(app.launchBoxPanel);
            app.n2OFillOpen = uibutton(app.launchBoxPanel, 'push');
            app.n2OFillOpen.ButtonPushedFcn = createCallbackFcn(app, @n2OFillOpenButtonPushed);
            app.n2OFillOpen.Text = 'N2O Fill Open';
            app.n2OFillOpenLamp = uilamp(app.launchBoxPanel);
            app.n2OVentClose = uibutton(app.launchBoxPanel, 'push');
            app.n2OVentClose.ButtonPushedFcn = createCallbackFcn(app, @n2OVentCloseButtonPushed);
            app.n2OVentClose.Text = 'N2O Vent Close';
            app.n2OVentCloseLamp = uilamp(app.launchBoxPanel);
            app.n2OVentOpen = uibutton(app.launchBoxPanel, 'push');
            app.n2OVentOpen.ButtonPushedFcn = createCallbackFcn(app, @n2OVentOpenButtonPushed);
            app.n2OVentOpen.Text = 'N2O Vent Open ';
            app.n2OVentOpenLamp = uilamp(app.launchBoxPanel);
            app.oxygenFillClose = uibutton(app.launchBoxPanel, 'push');
            app.oxygenFillClose.ButtonPushedFcn = createCallbackFcn(app, @oxygenFillCloseButtonPushed);
            app.oxygenFillClose.Text = 'Oxygen Fill Close';
            app.oxygenFillCloseLamp = uilamp(app.launchBoxPanel);
            app.qdAirClose = uibutton(app.launchBoxPanel, 'push');
            app.qdAirClose.ButtonPushedFcn = createCallbackFcn(app, @qdAirCloseButtonPushed);
            app.qdAirClose.Text = 'QD Air Close';
            app.qdAirCloseLamp = uilamp(app.launchBoxPanel);
            app.chA = uibutton(app.launchBoxPanel, 'push');
            app.chA.ButtonPushedFcn = createCallbackFcn(app, @chAButtonPushed);
            app.chA.Text = 'Ch A On';
            app.chALamp = uilamp(app.launchBoxPanel);
            app.oxygenFillOpen = uibutton(app.launchBoxPanel, 'push');
            app.oxygenFillOpen.ButtonPushedFcn = createCallbackFcn(app, @oxygenFillOpenButtonPushed);
            app.oxygenFillOpen.Text = 'Oxygen Fill Open';
            app.oxygenFillOpenLamp = uilamp(app.launchBoxPanel);
            app.qdAirOpen = uibutton(app.launchBoxPanel, 'push');
            app.qdAirOpen.ButtonPushedFcn = createCallbackFcn(app, @qdAirOpenButtonPushed);
            app.qdAirOpen.Text = 'QD Air Open';
            app.qdAirOpenLamp = uilamp(app.launchBoxPanel);
            app.aux = uibutton(app.launchBoxPanel, 'push');
            app.aux.ButtonPushedFcn = createCallbackFcn(app, @auxButtonPushed);
            app.aux.Text = 'AUX On';
            app.auxLamp = uilamp(app.launchBoxPanel);
            app.chB = uibutton(app.launchBoxPanel, 'push');
            app.chB.ButtonPushedFcn = createCallbackFcn(app, @chBButtonPushed);
            app.chB.Text = 'Ch B On';
            app.chBLamp = uilamp(app.launchBoxPanel);
            app.igniterArmLamp = uilamp(app.launchBoxPanel);
            app.igniterArm = uibutton(app.launchBoxPanel, 'push');
            app.igniterArm.ButtonPushedFcn = createCallbackFcn(app, @igniterArmButtonPushed);
            app.igniterArm.Text = 'Igniter Arm';
            app.igniterDisarm = uibutton(app.launchBoxPanel, 'push');
            app.igniterDisarm.ButtonPushedFcn = createCallbackFcn(app, @igniterDisarmButtonPushed);
            app.igniterDisarm.Text = 'Igniter Disarm';
            app.igniterContinuityLamp = uilamp(app.launchBoxPanel);
            app.igniterContTest = uibutton(app.launchBoxPanel, 'push');
            app.igniterContTest.ButtonPushedFcn = createCallbackFcn(app, @igniterContTestButtonPushed);
            app.igniterContTest.Text = 'Igniter Cont Test';
            app.buzzer = uibutton(app.launchBoxPanel, 'push');
            app.buzzer.ButtonPushedFcn = createCallbackFcn(app, @buzzerButtonPushed);
            app.buzzer.Text = 'Buzzer On';
            app.buzzerLamp = uilamp(app.launchBoxPanel);
            app.auxOff = uibutton(app.launchBoxPanel, 'push');
            app.auxOff.ButtonPushedFcn = createCallbackFcn(app, @auxOffButtonPushed);
            app.auxOff.Text = 'AUX Off';
            app.auxOffLamp = uilamp(app.launchBoxPanel);
            app.chAOff = uibutton(app.launchBoxPanel, 'push');
            app.chAOff.ButtonPushedFcn = createCallbackFcn(app, @chAOffButtonPushed);
            app.chAOff.Text = 'Ch A Off';
            app.chAOffLamp = uilamp(app.launchBoxPanel);
            app.chBOff = uibutton(app.launchBoxPanel, 'push');
            app.chBOff.ButtonPushedFcn = createCallbackFcn(app, @chBOffButtonPushed);
            app.chBOff.Text = 'Ch B Off';
            app.chBOffLamp = uilamp(app.launchBoxPanel);
            app.buzzerOff = uibutton(app.launchBoxPanel, 'push');
            app.buzzerOff.ButtonPushedFcn = createCallbackFcn(app, @buzzerOffButtonPushed);
            app.buzzerOff.Text = 'Buzzer Off';
            app.buzzerOffLamp = uilamp(app.launchBoxPanel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Control_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.mainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainWindow)
        end
    end
end