classdef DAQ_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        thermocouples         matlab.ui.control.UIAxes           % Thermoco...
        pressure              matlab.ui.control.UIAxes           % Pressure...
        pause                 matlab.ui.control.Button           % Pause
        start                 matlab.ui.control.Button           % Start
        LabelNumericEditField matlab.ui.control.Label            % Show Las...
        npoints               matlab.ui.control.NumericEditField % [0 150]
        loadCell              matlab.ui.control.UIAxes           % Load Cell
    end


    properties (Access = public)
        therm1;
        therm2;
        therm3;
        press1;
        press2;
    end

    properties (Access = private)
        timey;
        ctrl = Control_GUI;
        rawDAQ;
        pDAQ;
        rawIMU;
        pIMU;
        rawDAQTime;
        pDAQTime;
        rawIMUTime;
        pIMUTime;
        pLoadCellData;
        pLoadCellTime;
        pLCData;
    end

    methods (Access = private)
        
        function plotData(app, src, event)
            app.rawDAQ = app.ctrl.daqData;
            app.rawDAQTime = app.ctrl.daqTime;
            app.pDAQ = cell2mat(cellfun(@str2num,app.rawDAQ,'UniformOutput',0));
            app.pDAQTime = app.rawDAQTime;
            app.pLCData = app.ctrl.loadCellData;
            app.pLoadCellData = cell2mat(cellfun(@str2num,app.pLCData,'UniformOutput',0));
            app.pLoadCellTime = app.ctrl.loadCellTime;
            n = app.npoints.Value;
           
            if (n>=size(app.pDAQ))
                plot(app.thermocouples,app.pDAQTime,app.pDAQ(:,1),app.pDAQTime,app.pDAQ(:,2),app.pDAQTime,app.pDAQ(:,3));
                plot(app.pressure,app.pDAQTime,app.pDAQ(:,4),app.pDAQTime,app.pDAQ(:,5));
                plot(app.loadCell,app.pLoadCellTime,app.pLoadCellData);

            else
                plot(app.thermocouples,app.pDAQTime(1:n),app.pDAQ(1:n,1),app.pDAQTime(1:n),app.pDAQ(1:n,2),app.pDAQTime(1:n),app.pDAQ(1:n,3));
                plot(app.pressure,app.pDAQTime(1:n),app.pDAQ(1:n,4),app.pDAQTime(1:n),app.pDAQ(1:n,5));
                plot(app.loadCell,app.pLoadCellTime(1:n),app.pLoadCellData(1:n));
            end
            
            drawnow;
            
%             splitDAQ = regexp(cellstr(app.rawDAQ),',','split');
%             plot(app.thermocouples,app.timeArray,app.xArray);
        end

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.timey = timer('Period', .5, 'ExecutionMode', 'fixedSpacing');
            app.timey.TimerFcn = {@app.plotData};
        end

        % start button pushed function
        function startButtonPushed(app)
            start(app.timey);
        end

        % pause button pushed function
        function pauseButtonPushed(app)
            stop(app.timey);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.thermocouples = uiaxes(app.UIFigure);
            title(app.thermocouples, 'Thermocouples');
            xlabel(app.thermocouples, 'Time');
            ylabel(app.thermocouples, 'Temp');
            app.pressure = uiaxes(app.UIFigure);
            title(app.pressure, 'Pressure Sensors');
            xlabel(app.pressure, 'Time');
            ylabel(app.pressure, 'Pressure');
            app.pause = uibutton(app.UIFigure, 'push');
            app.pause.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed);
            app.pause.Text = 'Pause';
            app.start = uibutton(app.UIFigure, 'push');
            app.start.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed);
            app.start.Text = 'Start';
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'Show Last n Points';
            app.npoints = uieditfield(app.UIFigure, 'numeric');
            app.npoints.Limits = [0 150];
            app.npoints.Value = 20;
            app.loadCell = uiaxes(app.UIFigure);
            title(app.loadCell, 'Load Cell');
            xlabel(app.loadCell, 'Time');
            ylabel(app.loadCell, 'Force');
        end
    end

    methods (Access = public)

        % Construct app
        function app = DAQ_GUI()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end